classdef HAR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        MessageEditField           matlab.ui.control.EditField
        MessageEditFieldLabel      matlab.ui.control.Label
        Button                     matlab.ui.control.Button
        MQTTBrokerEditField        matlab.ui.control.EditField
        MQTTBrokerEditFieldLabel   matlab.ui.control.Label
        PortEditField              matlab.ui.control.NumericEditField
        PortEditFieldLabel         matlab.ui.control.Label
        CLientIDEditField          matlab.ui.control.EditField
        CLientIDEditFieldLabel     matlab.ui.control.Label
        TopicEditField             matlab.ui.control.EditField
        TopicEditFieldLabel        matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        PredictLabel               matlab.ui.control.Label
        EditField                  matlab.ui.control.EditField
        AverageTempLabel           matlab.ui.control.Label
        SimulationTimeEditField_2  slrealtime.ui.control.SimulationTimeEditField
        AverageBPMLabel            matlab.ui.control.Label
        SimulationTimeEditField    slrealtime.ui.control.SimulationTimeEditField
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            mqttPort = app.PortEditField.Value;
            
        end

        % Value changed function: TopicEditField
        function TopicEditFieldValueChanged(app, event)
            mqttTopic = app.TopicEditField.Value;
            
        end

        % Value changed function: CLientIDEditField
        function CLientIDEditFieldValueChanged(app, event)
            mqttClientID = app.CLientIDEditField.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function ClientInfoTextAreaValueChanged(app, event)
           app.ClientInfoTextAreaLabel.Text = event;
        end

        % Value changed function: MQTTBrokerEditField
        function MQTTBrokerEditFieldValueChanged(app, event)
            mqttBrokerURL = app.MQTTBrokerEditField.Value;

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            myMQTT = mqttclient('tcp://mqtt.eclipseprojects.io', Port = app.PortEditField.Value, ClientID=app.CLientIDEditField.Value);
            %Topic_sub = mqttTopic;
            Data = subscribe(myMQTT,app.TopicEditField.Value);
            
            %app.UIAxes=0;
            %app.UIAxes2=0;
            %app.UIAxes3=0;
            %modified code
            %-----------------
            x = 0;
            y= 0;
            z = 0;
            %------------------
            values_check = -1;
            xlim_index = -10;
            %
            buff = zeros(1,60,6);
            params = importONNXFunction("model_onnx2.onnx","modelHAR");
            i = 1;
            predicted_label = ["running","walking","sitting","hiking","swimming","swimming2"];
            %
            while true
               %messMqtt = read(myMQTT, Topic = mqttTopic);
               messMqtt = read(myMQTT, Topic = app.TopicEditField.Value);
               %app.MessageEditFieldValueChanged.Value = 'Hello';
               if ~isempty(messMqtt)
                    op = jsondecode(messMqtt.Data(1));
                    values = op.Time;
                    if(values ~= values_check)
                        temp = op.ax;
                        hum = op.ay;
                        ran = op.az;
                        %modified code
                        %--------------------------
                        x = [x, temp];
                        y = [y, hum];
                        z = [z, ran];
                        %
                        %buff = [buff,[temp,hum,ran,temp,hum,ran]];
                        buff(1, i, :) = [temp, hum, ran, temp, hum, ran];
                        i = i+1;
                        disp(size(buff))
                        disp(i)
                        %if(length(buff) == 60)
                            %disp("pass");
                        %if size(buff,2) >= 60
                        if isequal(size(buff), [1, 60, 6])
                            disp("pass")
                            [dense_1, ] = modelHAR(reshape(buff,[1,60,6]), params);
                            [~, predicted_class] = max(dense_1);
                            app.EditField.Value = predicted_label(predicted_class);
                            %EditFieldValueChanged(predicted_class);
                            %buff=[];
                            buff = zeros(1, 60, 6);
                            i=1;
                        end
                        %---------------------------
                        %app.UIAxes = [app.UIAxes,temp];
                        %app.UIAxes2 = [app.UIAxes2,hum];
                        %app.UIAxes3 = [app.UIAxes3,ran];


                        plot(app.UIAxes,x);
                        app.UIAxes.XAxis.Limits = [xlim_index, xlim_index + 20];
                        app.UIAxes.XGrid = 'on';
                        app.UIAxes.YGrid = 'on';
                        %title("X-axis");
                  

                        plot(app.UIAxes2,y);
                        app.UIAxes2.XAxis.Limits = [xlim_index, xlim_index + 20];
                        app.UIAxes2.XGrid = 'on';
                        app.UIAxes2.YGrid = 'on';
                        %title("Y-axis");
                  

                        plot(app.UIAxes3,z);
                        %ylim([0,10]);
                        app.UIAxes3.XAxis.Limits = [xlim_index, xlim_index + 20];
                        app.UIAxes3.XGrid = 'on';
                        app.UIAxes3.YGrid = 'on';
                        %title("Z-axis");
                        xlim_index = xlim_index+ 1;
                        drawnow     
                    end
               end   
               
            end
        end

        % Value changed function: MessageEditField
        function MessageEditFieldValueChanged(app, event)
            value = app.MessageEditField.Value;
            
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            %value = app.EditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {524, 524};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {258, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TopicEditFieldLabel = uilabel(app.LeftPanel);
            app.TopicEditFieldLabel.Text = 'Topic';
            app.TopicEditField = uieditfield(app.LeftPanel, 'text');
            app.TopicEditField.ValueChangedFcn = createCallbackFcn(app, @TopicEditFieldValueChanged, true);
            app.CLientIDEditFieldLabel = uilabel(app.LeftPanel);
            app.CLientIDEditFieldLabel.Text = 'CLientID';
            app.CLientIDEditField = uieditfield(app.LeftPanel, 'text');
            app.CLientIDEditField.ValueChangedFcn = createCallbackFcn(app, @CLientIDEditFieldValueChanged, true);
            app.PortEditFieldLabel = uilabel(app.LeftPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.MQTTBrokerEditFieldLabel = uilabel(app.LeftPanel);
            app.MQTTBrokerEditFieldLabel.Text = 'MQTT Broker';
            app.MQTTBrokerEditField = uieditfield(app.LeftPanel, 'text');
            app.MQTTBrokerEditField.ValueChangedFcn = createCallbackFcn(app, @MQTTBrokerEditFieldValueChanged, true);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.MessageEditFieldLabel = uilabel(app.LeftPanel);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.LeftPanel, 'text');
            app.MessageEditField.ValueChangedFcn = createCallbackFcn(app, @MessageEditFieldValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'X-axis')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Y-axis')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Z-axis')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SimulationTimeEditField = slrealtime.ui.control.SimulationTimeEditField(app.RightPanel);
            app.AverageBPMLabel = uilabel(app.RightPanel);
            app.AverageBPMLabel.Text = 'Average BPM';
            app.SimulationTimeEditField_2 = slrealtime.ui.control.SimulationTimeEditField(app.RightPanel);
            app.AverageTempLabel = uilabel(app.RightPanel);
            app.AverageTempLabel.Text = 'Average Temp';
            app.EditField = uieditfield(app.RightPanel, 'text');
            app.PredictLabel = uilabel(app.RightPanel);
            app.PredictLabel.Text = 'Predict';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HAR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end